classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PositiveLaplacianFilterButton  matlab.ui.control.Button
        SobelFilterButton              matlab.ui.control.Button
        OpeningButton                  matlab.ui.control.Button
        MedianFilterButton             matlab.ui.control.Button
        AddnoiseButton                 matlab.ui.control.Button
        ZoominButton                   matlab.ui.control.Button
        PowerLawTransformButton        matlab.ui.control.Button
        NegativeTransformButton        matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        InsertButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
          app.Image.ImageSource = 'cameraman.png';
        end

        % Button pushed function: NegativeTransformButton
        function NegativeTransformButtonPushed(app, event)
            I = imread('cameraman.png');
            [row, col] = size(I);
            K = uint8(zeros(row, col));
            for i = 1:row
                for j = 1:col
                    K(i,j) = 255-I(i,j); 
                end
            end
            imwrite(K,'negTrans.jpeg','JPEG');
            app.Image.ImageSource = 'negTrans.jpeg';
        end

        % Button pushed function: PowerLawTransformButton
        function PowerLawTransformButtonPushed(app, event)
            I = imread('cameraman.png');
            I=im2double(I);
            [row, col] = size(I);
            K = zeros(row, col);
            c=1;
            gammaa=8; 
            for i = 1:row
                for j = 1:col
                    K(i,j) = c*(I(i,j).^gammaa);
               end
            end
            imwrite(K,'powerLawTrans.jpeg','JPEG');
            app.Image.ImageSource = 'powerLawTrans.jpeg';

        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            I = imread('cameraman.png');
            I = im2gray(I);
            [m,n] = size(I);
            in = uint8(ones(2*m, 2*n));
            for i = 1:2*m
                for j = 1:2*n
                    p = i - floor(i/2);
                    q = j - floor(j/2);
                    in(i,j) = I(p,q);
                end
            end
            imwrite(in,'ZoomIn.jpeg','JPEG');
            app.Image.ImageSource = 'ZoomIn.jpeg';
        end

        % Button pushed function: AddnoiseButton
        function AddnoiseButtonPushed(app, event)
            I = imread('cameraman.png');
            J = imnoise(I,'salt & pepper');
            imwrite(J,'Noisy.jpeg','JPEG');
            app.Image.ImageSource = 'Noisy.jpeg';
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            S = imread('cameraman.png');
            R = S;
            [row, col] = size(S);
            for i = 1:row-2
                for j = 1:col-2 
                    N = S(i:i+2, j:j+2);
                    t = median(N(:));
                    R(i+1,j+1) = t;    
                end
            end
            imwrite(R,'MedianFilter.jpeg','JPEG');
            app.Image.ImageSource = 'MedianFilter.jpeg';

        end

        % Button pushed function: OpeningButton
        function OpeningButtonPushed(app, event)
            I = imread('cameraman.png');
            se = strel('disk',1);
            BW1 = imerode(I,se);
            BW2 = imdilate(BW1,se);
            imwrite(BW2,'Opening.jpeg','JPEG');
            app.Image.ImageSource = 'Opening.jpeg';
        end

        % Button pushed function: SobelFilterButton
        function SobelFilterButtonPushed(app, event)
            I = imread('cameraman.png');
            I = im2double(I);
            gx = [-1 0 1; -2 0 2; -1 0 1];
            gy = [1 2 1; 0 0 0; -1 -2 -1];
            d_dx = imfilter(I, gx);
            d_dy = imfilter(I, gy);
            I2 = sqrt(d_dx .^ 2 + d_dy .^ 2);
            t = 0.5;
            [row, col] = size(I2);
            for i = 1 : row
                for j = 1 : col
                    if(I2 < t)
                        I2(i, j) = 0;
                    end
                end
            end
            imwrite(I2,'Sobel.jpeg','JPEG');
            app.Image.ImageSource = 'Sobel.jpeg';
        end

        % Button pushed function: PositiveLaplacianFilterButton
        function PositiveLaplacianFilterButtonPushed(app, event)
            I = imread('cameraman.png');
            I = im2gray(I);
            I = im2double(I);
            LaplacianFil=[0 1 0; 1 -4 1; 0 1 0];
            I2=conv2(I, LaplacianFil, 'same');
            I2 = abs(I2);
            imwrite(I2,'Laplacian.jpeg','JPEG');
            app.Image.ImageSource = 'Laplacian.jpeg';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InsertButton = uibutton(app.UIFigure, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Text = 'Insert';
            app.Image = uiimage(app.UIFigure);
            app.NegativeTransformButton = uibutton(app.UIFigure, 'push');
            app.NegativeTransformButton.ButtonPushedFcn = createCallbackFcn(app, @NegativeTransformButtonPushed, true);
            app.NegativeTransformButton.Text = 'Negative Transform';
            app.PowerLawTransformButton = uibutton(app.UIFigure, 'push');
            app.PowerLawTransformButton.ButtonPushedFcn = createCallbackFcn(app, @PowerLawTransformButtonPushed, true);
            app.PowerLawTransformButton.Text = 'Power Law Transform';
            app.ZoominButton = uibutton(app.UIFigure, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.AddnoiseButton = uibutton(app.UIFigure, 'push');
            app.AddnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoiseButtonPushed, true);
            app.AddnoiseButton.Text = 'Add noise';
            app.MedianFilterButton = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.OpeningButton = uibutton(app.UIFigure, 'push');
            app.OpeningButton.ButtonPushedFcn = createCallbackFcn(app, @OpeningButtonPushed, true);
            app.OpeningButton.Text = 'Opening';
            app.SobelFilterButton = uibutton(app.UIFigure, 'push');
            app.SobelFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SobelFilterButtonPushed, true);
            app.SobelFilterButton.Text = 'Sobel Filter';
            app.PositiveLaplacianFilterButton = uibutton(app.UIFigure, 'push');
            app.PositiveLaplacianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @PositiveLaplacianFilterButtonPushed, true);
            app.PositiveLaplacianFilterButton.Text = 'Positive Laplacian Filter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end